classdef InterfaceBOMapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotResolutionButton            matlab.ui.control.Button
        PlotSNRButton                   matlab.ui.control.Button
        DualEchotimesLabel              matlab.ui.control.Label
        MultiEchotimesLabel             matlab.ui.control.Label
        TEsEditField                    matlab.ui.control.NumericEditField
        TEsEditFieldLabel               matlab.ui.control.Label
        TE1sEditField                   matlab.ui.control.NumericEditField
        TE1sEditFieldLabel              matlab.ui.control.Label
        nTEEditField                    matlab.ui.control.NumericEditField
        nTEEditFieldLabel               matlab.ui.control.Label
        TE1sEditField_2                 matlab.ui.control.NumericEditField
        TE1sEditField_2Label            matlab.ui.control.Label
        TEsEditField_2                  matlab.ui.control.NumericEditField
        TEsEditField_2Label             matlab.ui.control.Label
        ResolutionmmxmmSpinner          matlab.ui.control.Spinner
        ResolutionmmxmmSpinnerLabel     matlab.ui.control.Label
        Zone3materialKnob               matlab.ui.control.DiscreteKnob
        Zone3materialKnobLabel          matlab.ui.control.Label
        Zone2materialKnob               matlab.ui.control.DiscreteKnob
        Zone2materialKnobLabel          matlab.ui.control.Label
        Zone4materialKnob               matlab.ui.control.DiscreteKnob
        Zone4materialKnobLabel          matlab.ui.control.Label
        Zone1materialKnob               matlab.ui.control.DiscreteKnob
        Zone1materialKnobLabel          matlab.ui.control.Label
        OutsidematerialKnob             matlab.ui.control.DiscreteKnob
        OutsidematerialKnobLabel        matlab.ui.control.Label
        InsidematerialKnob              matlab.ui.control.DiscreteKnob
        InsidematerialKnobLabel         matlab.ui.control.Label
        SheppLoganmodelpropertiesLabel  matlab.ui.control.Label
        SphericalmodelpropertiesLabel   matlab.ui.control.Label
        AveragerelativeerrorEditField   matlab.ui.control.NumericEditField
        AveragerelativeerrorEditFieldLabel  matlab.ui.control.Label
        CalculateaverageerrorButton     matlab.ui.control.Button
        RownumberEditField              matlab.ui.control.NumericEditField
        RownumberLabel                  matlab.ui.control.Label
        DeleterowButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SNRSlider                       matlab.ui.control.Slider
        SNRSliderLabel                  matlab.ui.control.Label
        SimulategraphanddataButton      matlab.ui.control.Button
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        MultiEchoButton                 matlab.ui.control.ToggleButton
        DualEchoButton                  matlab.ui.control.ToggleButton
        FieldmapmodelButtonGroup        matlab.ui.container.ButtonGroup
        ZubalButton                     matlab.ui.control.ToggleButton
        SheppLoganButton                matlab.ui.control.ToggleButton
        SphericalButton                 matlab.ui.control.ToggleButton
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Table data, used for app.UITable

        % MAGNETIC SUCSEPTIBILITIES (ABSOLUTE)
        % Pure mineral oil (mag susc) = -8.842ppm
        % Silicone oil (magn susc) = -10.56ppm
        % Water (magn susc) = -9.032ppm
        % Air (magn susc) = 0.36ppm
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulategraphanddataButton
        function SimulategraphanddataButtonPushed(app, event)

            res=app.ResolutionmmxmmSpinner.Value; % Takes the resolution value from the spinner

            switch app.FieldmapmodelButtonGroup.SelectedObject % Evaluates the chosen model

                %%%%%%%%%%%%%%%%%%%%% SPHERICAL MODEL %%%%%%%%%%%%%%%%%%%%%
                case app.SphericalButton 
                    
                    % Evaluate the chosen materials and store their values
                    inside=0;outside=0; % Initialize magnetic susceptibility values for the materials inside and outside the sphere
                    switch app.InsidematerialKnob.Value % Evaluates the chosen inside material
                        case {'0.36','0.3600'} % Air
                            inside=0.36; 
                        case '-9.032' % Water
                            inside=-9.032;
                        case '-8.842' % Pure mineral oil
                            inside=-8.842;
                        case '-10.56' % Silicone oil
                            inside=-10.56;
                    end
                    switch app.OutsidematerialKnob.Value % Evaluates the chosen outside material
                        case {'0.36','0.3600'}
                            outside=0.36;
                        case '-9.032'
                            outside=-9.032;
                        case '-8.842'
                            outside=-8.842;
                        case '-10.56'
                            outside=-10.56;
                    end
                    
                    % Create susceptibilities distribution
                    spherical_sus_dist = Spherical( [128 128 128], [res res res], 25, [(inside*(10^-6)) (outside*(10^-6))]); % Magnetic susceptibility distribution
                    spherical_dBz = FBFest(spherical_sus_dist.volume,spherical_sus_dist.image_res, spherical_sus_dist.matrix, 'Spherical' ); % Compute deltaB0 for the simulated susceptibility distribution
                    spherical_dBz.save('spherical_dBz.nii');

                    % Create the object spherical_vol using NumericalModel with the right materials
                    switch app.InsidematerialKnob.Value
                        case '0.36'
                            switch app.OutsidematerialKnob.Value
                                case '0.36'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','air');
                                case '-9.032'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','water');
                                case '-8.842'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','pure_mineral_oil');
                                case '-10.56'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','silicone_oil');
                            end
                         case '-9.032'
                            switch app.OutsidematerialKnob.Value
                                case '0.36'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','air');
                                case '-9.032'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','water');
                                case '-8.842'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','pure_mineral_oil');
                                case '-10.56'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','silicone_oil');
                            end
                         case '-8.842'
                            switch app.OutsidematerialKnob.Value
                                case '0.36'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','air');
                                case '-9.032'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','water');
                                case '-8.842'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','pure_mineral_oil');
                                case '-10.56'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','silicone_oil');
                            end
                         case '-10.56'
                            switch app.OutsidematerialKnob.Value
                                case '0.36'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','air');
                                case '-9.032'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','water');
                                case '-8.842'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','pure_mineral_oil');
                                case '-10.56'
                                    spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','silicone_oil');
                            end
                    end
                    spherical_vol.generate_deltaB0('load_external', 'spherical_dBz.nii'); % Generate the deltaB0 matrix

                    % Evaluate the chosen echo times and store their values
                    if app.MethodButtonGroup.SelectedObject==app.MultiEchoButton
                        echoTimes=zeros(1,app.nTEEditField.Value);
                        for i=1:app.nTEEditField.Value
                            echoTimes(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                    else
                        echoTimes=zeros(1,2);
                        echoTimes(1,1)=app.TE1sEditField.Value;
                        echoTimes(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                    end
                    
                    % Generate and show the true map we wish to obtain
                    spherical_vol.simulate_measurement(90, echoTimes, app.SNRSlider.Value); % Simulate T2* decay
                    trueMap=spherical_vol.deltaB0(:,:,64)*(spherical_vol.gamma / (2*pi)); % Defines the true map
                    trueMapRes=trueMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res))); % Takes the resolution into account to generate a true map with proper size
                    set(app.UIAxes,'CLim',[min(trueMapRes,[],'all') max(trueMapRes,[],'all')+1]);set(app.UIAxes_2,'CLim',[min(trueMapRes,[],'all') max(trueMapRes,[],'all')+1]);set(app.UIAxes_3,'CLim',[min(trueMapRes,[],'all') max(trueMapRes,[],'all')+1]);
                    axis(app.UIAxes_2,[1 round(128/res) 1 round(128/res)])
                    imagesc(app.UIAxes_2,squeeze(trueMapRes))
                    colorbar(app.UIAxes_2);

                    % Generate and show the map obtained with the chosen method
                    magn = spherical_vol.getMagnitude;
                    phase = spherical_vol.getPhase;
                    compl_vol = magn.*exp(1i*phase);
                    switch app.MethodButtonGroup.SelectedObject % Evaluates the chosen method
                        case app.DualEchoButton
                            [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimes); % Generate the map with dual-echo
                            generatedMap=dual_echo_delf(:,:,64); % Take a z-slice 
                            generatedMapRes=generatedMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res))); % Takes the resolution into account to generate a map with proper size
                            axis(app.UIAxes,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes,squeeze(generatedMapRes))
                            colorbar(app.UIAxes);
                            diff_dualecho = generatedMapRes-trueMapRes; % Generate the difference map
                            axis(app.UIAxes_3,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes_3,squeeze(diff_dualecho));colorbar(app.UIAxes_3)
                            
                            % Calculate the mean squared error
                            SE = abs(trueMapRes-generatedMapRes).^2; % Matrix of squared errors
                            MSE = sum(SE(:))/numel(trueMapRes); % Mean squared error

                            % Calculate the relative error without using the null values within the true map (to avoid division by 0)
                            sumRe=1;
                            RE=zeros(size(trueMapRes));
                            for i=1:size(trueMapRes,1)
                                for j=1:size(trueMapRes,2)
                                    if round(trueMapRes(i,j))~=0
                                        RE(i,j)=abs(diff_dualecho(i,j)/trueMapRes(i,j));
                                        sumRe=sumRe+1;
                                    end
                                end
                            end
                            MRE=100*sum(RE(:))/sumRe-1; % Average relative error
                            
                            % Adding data to table
                            NewData={'Spherical' 'Dual-Echo' app.SNRSlider.Value res MRE MSE};
                            app.UITable.Data=[app.Data;NewData];
                            app.Data=app.UITable.Data;

                        case app.MultiEchoButton
                            % Same process for multi-echo
                            [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimes); 
                            generatedMap=multi_echo_delf(:,:,64);
                            generatedMapRes=generatedMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                            axis(app.UIAxes,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes,squeeze(generatedMapRes))
                            colorbar(app.UIAxes);
                            diff_multiecho = generatedMapRes-trueMapRes;
                            axis(app.UIAxes_3,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes_3,squeeze(diff_multiecho)); colorbar(app.UIAxes_3)
                            SE = abs(trueMapRes-generatedMapRes).^2;
                            MSE = sum(SE(:))/numel(trueMapRes);
                            sumRe=1;
                            RE=zeros(size(trueMapRes));
                            for i=1:size(trueMapRes,1)
                                for j=1:size(trueMapRes,2)
                                    if trueMapRes(i,j)~=0
                                        RE(i,j)=abs(diff_multiecho(i,j)/trueMapRes(i,j));
                                        sumRe=sumRe+1;
                                    end
                                end
                            end
                            MRE=100*sum(RE(:))/sumRe-1;
                            NewData={'Spherical' 'Multi-Echo' app.SNRSlider.Value res MRE MSE};
                            app.UITable.Data=[app.Data;NewData];
                            app.Data=app.UITable.Data;
                    end

                %%%%%%%%%%%%%%%%%% SHEPP-LOGAN MODEL %%%%%%%%%%%%%%%%%%%%%%
                case app.SheppLoganButton

                    % Evaluate the chosen materials and store their values
                    zone1=0;zone2=0;zone3=0;zone4=0; % Initialize magnetic susceptibility values for the materials inside and outside the sphere
                    switch app.Zone1materialKnob.Value % Evaluates the chosen inside material
                        case {'0.36','0.3600'} % Air
                            zone1=0.36;
                        case '-9.032' % Water
                            zone1=-9.032;
                        case '-8.842' % Pure mineral oil
                            zone1=-8.842;
                        case '-10.56' % Silicone oil
                            zone1=-10.56;
                    end
                    switch app.Zone2materialKnob.Value
                        case {'0.36','0.3600'}                           
                            zone2=0.36;
                        case '-9.032'
                            zone2=-9.032;
                        case '-8.842'
                            zone2=-8.842;
                        case '-10.56'
                            zone2=-10.56;
                    end
                    switch app.Zone3materialKnob.Value
                        case {'0.36','0.3600'}
                            zone3=0.36;
                        case '-9.032'
                            zone3=-9.032;
                        case '-8.842'
                            zone3=-8.842;
                        case '-10.56'
                            zone3=-10.56;
                    end
                    switch app.Zone4materialKnob.Value
                        case {'0.36','0.3600'}
                            zone4=0.36;
                        case '-9.032'
                            zone4=-9.032;
                        case '-8.842'
                            zone4=-8.842;
                        case '-10.56'
                            zone4=-10.56;
                    end
                    
                    % Create susceptibilities distribution
                    shepplogan_sus_dist = SheppLogan(round(128/res), res, [zone1*10^-6 zone2*10^-6 zone3*10^-6 zone4*10^-6]); % Magnetic susceptibility distribution
                    shepplogan_dBz = FBFest(shepplogan_sus_dist.volume, shepplogan_sus_dist.image_res, shepplogan_sus_dist.matrix, 'SheppLogan' ); % Compute deltaB0 for the simulated susceptibility distribution
                    shepplogan_dBz.save('shepplogan_dBz.nii');

                    % Create the object sphepplogan_vol using NumericalModel with the right materials
                    shepplogan_vol = NumericalModel('Shepp-Logan3d',round(128/res));
                    shepplogan_vol.generate_deltaB0('load_external', 'shepplogan_dBz.nii'); % Generate the deltaB0 matrix

                    % Evaluate the chosen echo times and store their values
                    if app.MethodButtonGroup.SelectedObject==app.MultiEchoButton
                        echoTimes=zeros(1,app.nTEEditField.Value);
                        for i=1:app.nTEEditField.Value
                            echoTimes(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                    else
                        echoTimes=zeros(1,2);
                        echoTimes(1,1)=app.TE1sEditField.Value;
                        echoTimes(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                    end

                    % Generate and show the true map we wish to obtain
                    shepplogan_vol.simulate_measurement(90, echoTimes, app.SNRSlider.Value); % Simulate T2* decay
                    trueMap=shepplogan_vol.deltaB0(:,:,round(64/res) )*(shepplogan_vol.gamma / (2*pi)); % Defines the true map
                    set(app.UIAxes,'CLim',[min(trueMap,[],'all') max(trueMap,[],'all')]);set(app.UIAxes_2,'CLim',[min(trueMap,[],'all') max(trueMap,[],'all')]);set(app.UIAxes_3,'CLim',[min(trueMap,[],'all') max(trueMap,[],'all')]);
                    axis(app.UIAxes_2,[1 round(128/res) 1 round(128/res)])
                    imagesc(app.UIAxes_2,squeeze(trueMap))
                    colorbar(app.UIAxes_2);

                    % Generate and show the map obtained with the chosen method
                    magn = shepplogan_vol.getMagnitude;
                    phase = shepplogan_vol.getPhase;
                    compl_vol = magn.*exp(1i*phase);
                    switch app.MethodButtonGroup.SelectedObject % Evaluates the chosen method
                        case app.DualEchoButton
                            [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimes); % Generate the map with dual-echo
                            generatedMap=dual_echo_delf(:,:,round(64/res)); % Take a z-slice
                            axis(app.UIAxes,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes,squeeze(generatedMap))
                            colorbar(app.UIAxes);
                            diff_dualecho = generatedMap-trueMap; % Generate the difference map
                            axis(app.UIAxes_3,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes_3,squeeze(diff_dualecho)); colorbar(app.UIAxes_3)

                            % Calculate the mean squared error
                            SE = abs(trueMap-generatedMap).^2;
                            MSE = sum(SE(:))/numel(trueMap);

                            % Calculate the relative error
                            Re=100*mean(abs(diff_dualecho./trueMap),'all');

                            % Adding data to table
                            NewData={'Shepp-Logan' 'Dual-Echo' app.SNRSlider.Value res Re MSE};
                            app.UITable.Data=[app.Data;NewData];
                            app.Data=app.UITable.Data;

                        case app.MultiEchoButton
                            % Same process as dual-echo
                            [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimes); 
                            generatedMap=multi_echo_delf(:,:,round(64/res));
                            axis(app.UIAxes,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes,squeeze(generatedMap))
                            colorbar(app.UIAxes);
                            diff_multiecho = generatedMap-trueMap;
                            axis(app.UIAxes_3,[1 round(128/res) 1 round(128/res)])
                            imagesc(app.UIAxes_3,squeeze(diff_multiecho)); colorbar(app.UIAxes_3)
                            % Adding data to table
                            SE = abs(trueMap-generatedMap).^2;
                            MSE = sum(SE(:))/numel(trueMap);
                            Re=100*mean(abs(diff_multiecho./trueMap),'all');
                            NewData={'Shepp-Logan' 'Multi-Echo' app.SNRSlider.Value res Re MSE};
                            app.UITable.Data=[app.Data;NewData];
                            app.Data=app.UITable.Data;
                    end

                %%%%%%%%%%%%%%%%%%%%%% ZUBAL MODEL %%%%%%%%%%%%%%%%%%%%%%%%
                case app.ZubalButton

                    % Create susceptibilities distribution
                    zubal_sus_dist = Zubal('../zubal_EAO.nii');% Magnetic susceptibility distribution

                    % Create variables for the usage of griddedInterpolant function 
                    sz = size(zubal_sus_dist.volume); 
                    xg = 1:sz(1);
                    yg = 1:sz(2);
                    F = griddedInterpolant({xg,yg},double(zubal_sus_dist.volume));
                    xq = linspace(1,sz(1),round(256/res))';
                    yq = linspace(1,sz(2),round(256/res))';
                    zubal_sus_dist.volume = F({xq,yq});

                    zubal_dBz = FBFest(zubal_sus_dist.volume, [res res res], [round(256/res) round(256/res) 128], 'Zubal' ); % Compute deltaB0 for the simulated susceptibility distribution
                    zubal_dBz.save('zubal_EAO_dBz.nii');

                    % Create the object zubal_vol using NumericalModel with the right materials
                    zubal_vol = NumericalModel('Zubal','ShimmingToolboxMatlab/zubal_EAO.nii');
                    zubal_vol.generate_deltaB0('load_external', 'zubal_EAO_dBz.nii'); % Generate the deltaB0 matrix

                    % Use the griddedInterpolant function to modify the matrix sizes
                    %F = griddedInterpolant({xg,yg},double(zubal_vol.deltaB0));
                    %zubal_vol.deltaB0 = F({xq,yq});
                    zubal_vol.numVox=[round(256/res) round(256/res) 128];
                    F = griddedInterpolant({xg,yg},double(zubal_vol.starting_volume));
                    zubal_vol.starting_volume=F({xq,yq});
                    F = griddedInterpolant({xg,yg},double(zubal_vol.volume.protonDensity));
                    zubal_vol.volume.protonDensity=F({xq,yq});
                    F = griddedInterpolant({xg,yg},double(zubal_vol.volume.T2star));
                    zubal_vol.volume.T2star=F({xq,yq});

                    % Evaluate the chosen echo times and store their values
                    if app.MethodButtonGroup.SelectedObject==app.MultiEchoButton
                        echoTimes=zeros(1,app.nTEEditField.Value);
                        for i=1:app.nTEEditField.Value
                            echoTimes(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                    else
                        echoTimes=zeros(1,2);
                        echoTimes(1,1)=app.TE1sEditField.Value;
                        echoTimes(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                    end
                    
                    % Generate and show the true map we wish to obtain
                    zubal_vol.simulate_measurement(90, echoTimes, app.SNRSlider.Value); % Simulate T2* decay
                    trueMap=zubal_vol.deltaB0(:,:,64)*(zubal_vol.gamma / (2*pi)); % Defines the true map
                    set(app.UIAxes,'CLim',[min(trueMap,[],'all') max(trueMap,[],'all')]);set(app.UIAxes_2,'CLim',[min(trueMap,[],'all') max(trueMap,[],'all')]);set(app.UIAxes_3,'CLim',[min(trueMap,[],'all') max(trueMap,[],'all')]);
                    axis(app.UIAxes_2,[1 round(256/res) 1 round(256/res)])
                    imagesc(app.UIAxes_2,squeeze(trueMap))
                    colorbar(app.UIAxes_2);

                    % Generate and show the map obtained with the chosen method
                    magn = zubal_vol.getMagnitude;
                    phase = zubal_vol.getPhase;
                    compl_vol = magn.*exp(1i*phase);
                    switch app.MethodButtonGroup.SelectedObject % Evaluates the chosen method
                        case app.DualEchoButton
                            [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimes); % Generate the map with dual-echo
                            generatedMap=dual_echo_delf(:,:,64); % Take a z-slice
                            axis(app.UIAxes,[1 round(256/res) 1 round(256/res)])
                            imagesc(app.UIAxes,squeeze(generatedMap))
                            colorbar(app.UIAxes);
                            diff_dualecho = generatedMap-trueMap; % Generate the difference map
                            axis(app.UIAxes_3,[1 round(256/res) 1 round(256/res)])
                            imagesc(app.UIAxes_3,squeeze(diff_dualecho)); colorbar(app.UIAxes_3)

                            % Calculate the mean squared error
                            SE = abs(trueMap-generatedMap).^2;
                            MSE = sum(SE(:))/numel(trueMap);

                            % Calculate the relative error
                            Re=100*mean(abs(diff_dualecho./trueMap),'all');

                            % Add data to table
                            NewData={'Zubal' 'Dual-Echo' app.SNRSlider.Value res Re MSE};
                            app.UITable.Data=[app.Data;NewData];
                            app.Data=app.UITable.Data;

                        case app.MultiEchoButton
                            % Same process as dual-echo
                            [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimes); 
                            generatedMap=multi_echo_delf(:,:,64);
                            axis(app.UIAxes,[1 round(256/res) 1 round(256/res)])
                            imagesc(app.UIAxes,squeeze(generatedMap))
                            colorbar(app.UIAxes);
                            diff_multiecho = generatedMap-trueMap;
                            axis(app.UIAxes_3,[1 round(256/res) 1 round(256/res)])
                            imagesc(app.UIAxes_3,squeeze(diff_multiecho)); colorbar(app.UIAxes_3)
                            % Adding data to table
                            SE = abs(trueMap-generatedMap).^2;
                            MSE = sum(SE(:))/numel(trueMap);
                            Re=100*mean(abs(diff_multiecho./trueMap),'all');
                            NewData={'Zubal' 'Multi-Echo' app.SNRSlider.Value res Re MSE};
                            app.UITable.Data=[app.Data;NewData];
                            app.Data=app.UITable.Data;
                    end
            end
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            RowNumber=app.RownumberEditField.Value;
            if size(app.Data,1)>=RowNumber && RowNumber>=0
                app.UITable.Data(RowNumber,:)=[];
                app.Data=app.UITable.Data;
            end
        end

        % Button pushed function: CalculateaverageerrorButton
        function CalculateaverageerrorButtonPushed(app, event)
            NbRows=size(app.Data,1);
            sum=0;
            for i=1:NbRows
                sum=sum+app.UITable.Data{i,5};
            end
            Average=sum/NbRows;
            app.AveragerelativeerrorEditField.Value=Average;
        end

        % Button pushed function: PlotSNRButton
        function PlotSNRButtonPushed(app, event)
            % When the button is pushed, this function calculates the relative errors of dual-echo and multi-echo for the chosen parameters, but for a series of SNR values defined below
            xPlot = [50 200 350 500];
            yPlotDualEcho= [0 0 0 0];
            yPlotMultiEcho= [0 0 0 0];
            for SNR = [50 200 350 500] % SNR values used for the simulation
                res=app.ResolutionmmxmmSpinner.Value;
                switch app.FieldmapmodelButtonGroup.SelectedObject            
                    case app.SphericalButton
                        inside=0;outside=0;
                        switch app.InsidematerialKnob.Value
                            case {'0.36','0.3600'}
                                inside=0.36;
                            case '-9.032'
                                inside=-9.032;
                            case '-8.842'
                                inside=-8.842;
                            case '-10.56'
                                inside=-10.56;
                        end
                        switch app.OutsidematerialKnob.Value
                            case {'0.36','0.3600'}
                                outside=0.36;
                            case '-9.032'
                                outside=-9.032;
                            case '-8.842'
                                outside=-8.842;
                            case '-10.56'
                                outside=-10.56;
                        end
                        spherical_sus_dist = Spherical( [128 128 128], [res res res], 25, [(inside*(10^-6)) (outside*(10^-6))]);
                        spherical_dBz = FBFest(spherical_sus_dist.volume,spherical_sus_dist.image_res, spherical_sus_dist.matrix, 'Spherical' );
                        spherical_dBz.save('spherical_dBz.nii');
                        switch app.InsidematerialKnob.Value
                            case '0.36'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','silicone_oil');
                                end
                             case '-9.032'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','silicone_oil');
                                end
                             case '-8.842'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','silicone_oil');
                                end
                             case '-10.56'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','silicone_oil');
                                end
                        end
                        spherical_vol.generate_deltaB0('load_external', 'spherical_dBz.nii');

                        % Calculating for dual-echo
                        echoTimesD=zeros(1,2);
                        echoTimesD(1,1)=app.TE1sEditField.Value;
                        echoTimesD(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                        spherical_vol.simulate_measurement(90, echoTimesD, SNR);
                        trueMap=spherical_vol.deltaB0(:,:,64)*(spherical_vol.gamma / (2*pi));
                        trueMapRes=trueMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        magn = spherical_vol.getMagnitude;
                        phase = spherical_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimesD);
                        generatedMap=dual_echo_delf(:,:,64);
                        generatedMapRes=generatedMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        %SE = abs(trueMapRes-generatedMapRes).^2;
                        %MSE = sum(SE(:))/numel(trueMapRes);
                        Re=100*mean(abs((trueMapRes-generatedMapRes)./trueMapRes),'all');
                        yPlotDualEcho(find(xPlot==SNR))=Re;

                        % Calculating for multi-echo
                        echoTimesM=zeros(1,app.nTEEditField.Value);
                        for i=1:app.nTEEditField.Value
                            echoTimesM(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                        spherical_vol.simulate_measurement(90, echoTimesM, SNR);
                        trueMap=spherical_vol.deltaB0(:,:,64)*(spherical_vol.gamma / (2*pi));
                        trueMapRes=trueMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        magn = spherical_vol.getMagnitude;
                        phase = spherical_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimesM); 
                        generatedMap=multi_echo_delf(:,:,64);
                        generatedMapRes=generatedMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        %SE = abs(trueMapRes-generatedMapRes).^2;
                        %MSE = sum(SE(:))/numel(trueMapRes);
                        Re=100*mean(abs((trueMapRes-generatedMapRes)./trueMapRes),'all');
                        yPlotMultiEcho(find(xPlot==SNR))=Re;

                    case app.SheppLoganButton
                        zone1=0;zone2=0;zone3=0;zone4=0;
                        switch app.Zone1materialKnob.Value
                            case {'0.36','0.3600'}
                                zone1=0.36;
                            case '-9.032'
                                zone1=-9.032;
                            case '-8.842'
                                zone1=-8.842;
                            case '-10.56'
                                zone1=-10.56;
                        end
                        switch app.Zone2materialKnob.Value
                            case {'0.36','0.3600'}                           
                                zone2=0.36;
                            case '-9.032'
                                zone2=-9.032;
                            case '-8.842'
                                zone2=-8.842;
                            case '-10.56'
                                zone2=-10.56;
                        end
                        switch app.Zone3materialKnob.Value
                            case {'0.36','0.3600'}
                                zone3=0.36;
                            case '-9.032'
                                zone3=-9.032;
                            case '-8.842'
                                zone3=-8.842;
                            case '-10.56'
                                zone3=-10.56;
                        end
                        switch app.Zone4materialKnob.Value
                            case {'0.36','0.3600'}
                                zone4=0.36;
                            case '-9.032'
                                zone4=-9.032;
                            case '-8.842'
                                zone4=-8.842;
                            case '-10.56'
                                zone4=-10.56;
                        end
                        shepplogan_sus_dist = SheppLogan(round(128/res), res, [zone1*10^-6 zone2*10^-6 zone3*10^-6 zone4*10^-6]);
                        shepplogan_dBz = FBFest(shepplogan_sus_dist.volume, shepplogan_sus_dist.image_res, shepplogan_sus_dist.matrix, 'SheppLogan' );
                        shepplogan_dBz.save('shepplogan_dBz.nii');
                        shepplogan_vol = NumericalModel('Shepp-Logan3d',round(128/res));
                        shepplogan_vol.generate_deltaB0('load_external', 'shepplogan_dBz.nii');

                        % Calculating for dual-echo
                        echoTimesD=zeros(1,2);
                        echoTimesD(1,1)=app.TE1sEditField.Value;
                        echoTimesD(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                        shepplogan_vol.simulate_measurement(90, echoTimesD, SNR);
                        trueMap=shepplogan_vol.deltaB0(:,:,round(64/res) )*(shepplogan_vol.gamma / (2*pi));
                        magn = shepplogan_vol.getMagnitude;
                        phase = shepplogan_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimesD);
                        generatedMap=dual_echo_delf(:,:,round(64/res));
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotDualEcho(find(xPlot==SNR))=Re;

                        % Calculating for multi-echo
                        echoTimesM=zeros(1,app.nTEEditField.Value);
                        for i=1:app.nTEEditField.Value
                            echoTimesM(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                        shepplogan_vol.simulate_measurement(90, echoTimesM, SNR);
                        trueMap=shepplogan_vol.deltaB0(:,:,round(64/res) )*(shepplogan_vol.gamma / (2*pi));
                        magn = shepplogan_vol.getMagnitude;
                        phase = shepplogan_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimesM); 
                        generatedMap=multi_echo_delf(:,:,round(64/res));
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotMultiEcho(find(xPlot==SNR))=Re;

                    case app.ZubalButton
                        zubal_sus_dist = Zubal('../zubal_EAO.nii');
                        % Resolution 
                        sz = size(zubal_sus_dist.volume);
                        xg = 1:sz(1);
                        yg = 1:sz(2);
                        F = griddedInterpolant({xg,yg},double(zubal_sus_dist.volume));
                        xq = linspace(1,sz(1),round(256/res))';
                        yq = linspace(1,sz(2),round(256/res))';
                        zubal_sus_dist.volume = F({xq,yq});
                        zubal_dBz = FBFest(zubal_sus_dist.volume, [res res res], [round(256/res) round(256/res) 128], 'Zubal' );
                        zubal_dBz.save('zubal_EAO_dBz.nii');
                        zubal_vol = NumericalModel('Zubal','ShimmingToolboxMatlab/zubal_EAO.nii');
                        zubal_vol.generate_deltaB0('load_external', 'zubal_EAO_dBz.nii');

                        % Resolution 
                        zubal_vol.numVox=[round(256/res) round(256/res) 128];
                        F = griddedInterpolant({xg,yg},double(zubal_vol.starting_volume));
                        zubal_vol.starting_volume=F({xq,yq});
                        F = griddedInterpolant({xg,yg},double(zubal_vol.volume.protonDensity));
                        zubal_vol.volume.protonDensity=F({xq,yq});
                        F = griddedInterpolant({xg,yg},double(zubal_vol.volume.T2star));

                        zubal_vol.volume.T2star=F({xq,yq});
                        % Calculating for dual-echo
                        echoTimesD=zeros(1,2);
                        echoTimesD(1,1)=app.TE1sEditField.Value;
                        echoTimesD(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                        zubal_vol.simulate_measurement(90, echoTimesD, SNR);
                        trueMap=zubal_vol.deltaB0(:,:,64)*(zubal_vol.gamma / (2*pi));
                        magn = zubal_vol.getMagnitude;
                        phase = zubal_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimesD);
                        generatedMap=dual_echo_delf(:,:,64);
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotDualEcho(find(xPlot==SNR))=Re;

                        % Calculating for multi-echo
                        for i=1:app.nTEEditField.Value
                            echoTimesM(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                        zubal_vol.simulate_measurement(90, echoTimesM, SNR);
                        trueMap=zubal_vol.deltaB0(:,:,64)*(zubal_vol.gamma / (2*pi));
                        magn = zubal_vol.getMagnitude;
                        phase = zubal_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimesM); 
                        generatedMap=multi_echo_delf(:,:,64);
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotMultiEcho(find(xPlot==SNR))=Re;
                end
            end
            plot(app.UIAxes2,xPlot,yPlotDualEcho)
            hold(app.UIAxes2,'on') 
            plot(app.UIAxes2,xPlot,yPlotMultiEcho)
            hold(app.UIAxes2,'off')
            xlabel(app.UIAxes2,'SNR')
            legend(app.UIAxes2,'Dual-Echo','Multi-Echo') 
        end

        % Button pushed function: PlotResolutionButton
        function PlotResolutionButtonPushed(app, event)
             % When the button is pushed, this function calculates the relative errors of dual-echo and multi-echo for the chosen parameters, but for a series of resolution values defined below          
            xPlot = [1 2 4 8];
            yPlotDualEcho= [0 0 0 0];
            yPlotMultiEcho= [0 0 0 0];
            for res = [1 2 4 8] % Resolution values used for the simulation
                switch app.FieldmapmodelButtonGroup.SelectedObject            
                    case app.SphericalButton
                        inside=0;outside=0;
                        switch app.InsidematerialKnob.Value
                            case {'0.36','0.3600'}
                                inside=0.36;
                            case '-9.032'
                                inside=-9.032;
                            case '-8.842'
                                inside=-8.842;
                            case '-10.56'
                                inside=-10.56;
                        end
                        switch app.OutsidematerialKnob.Value
                            case {'0.36','0.3600'}
                                outside=0.36;
                            case '-9.032'
                                outside=-9.032;
                            case '-8.842'
                                outside=-8.842;
                            case '-10.56'
                                outside=-10.56;
                        end
                        spherical_sus_dist = Spherical( [128 128 128], [res res res], 25, [(inside*(10^-6)) (outside*(10^-6))]);
                        spherical_dBz = FBFest(spherical_sus_dist.volume,spherical_sus_dist.image_res, spherical_sus_dist.matrix, 'Spherical' );
                        spherical_dBz.save('spherical_dBz.nii');
                        switch app.InsidematerialKnob.Value
                            case '0.36'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'air','silicone_oil');
                                end
                             case '-9.032'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'water','silicone_oil');
                                end
                             case '-8.842'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'pure_mineral_oil','silicone_oil');
                                end
                             case '-10.56'
                                switch app.OutsidematerialKnob.Value
                                    case '0.36'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','air');
                                    case '-9.032'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','water');
                                    case '-8.842'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','pure_mineral_oil');
                                    case '-10.56'
                                        spherical_vol = NumericalModel('Spherical3d',round(128/res),res,25,'silicone_oil','silicone_oil');
                                end
                        end
                        spherical_vol.generate_deltaB0('load_external', 'spherical_dBz.nii');
                        % Calculating for dual-echo
                        echoTimesD=zeros(1,2);
                        echoTimesD(1,1)=app.TE1sEditField.Value;
                        echoTimesD(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                        spherical_vol.simulate_measurement(90, echoTimesD, app.SNRSlider.Value);
                        trueMap=spherical_vol.deltaB0(:,:,64)*(spherical_vol.gamma / (2*pi));
                        trueMapRes=trueMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        magn = spherical_vol.getMagnitude;
                        phase = spherical_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimesD);
                        generatedMap=dual_echo_delf(:,:,64);
                        generatedMapRes=generatedMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        %SE = abs(trueMapRes-generatedMapRes).^2;
                        %MSE = sum(SE(:))/numel(trueMapRes);
                        Re=100*mean(abs((trueMapRes-generatedMapRes)./trueMapRes),'all');
                        yPlotDualEcho(find(xPlot==res))=Re;
                        % Calculating for multi-echo
                        echoTimesM=zeros(1,app.nTEEditField.Value);
                        for i=1:app.nTEEditField.Value
                            echoTimesM(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                        spherical_vol.simulate_measurement(90, echoTimesM, app.SNRSlider.Value);
                        trueMap=spherical_vol.deltaB0(:,:,64)*(spherical_vol.gamma / (2*pi));
                        trueMapRes=trueMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        magn = spherical_vol.getMagnitude;
                        phase = spherical_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimesM); 
                        generatedMap=multi_echo_delf(:,:,64);
                        generatedMapRes=generatedMap(round(65-128/(2*res)):round(64+128/(2*res)),round(65-128/(2*res)):round(64+128/(2*res)));
                        %SE = abs(trueMapRes-generatedMapRes).^2;
                        %MSE = sum(SE(:))/numel(trueMapRes);
                        Re=100*mean(abs((trueMapRes-generatedMapRes)./trueMapRes),'all');
                        yPlotMultiEcho(find(xPlot==res))=Re;

                    case app.SheppLoganButton
                        zone1=0;zone2=0;zone3=0;zone4=0;
                        switch app.Zone1materialKnob.Value
                            case {'0.36','0.3600'}
                                zone1=0.36;
                            case '-9.032'
                                zone1=-9.032;
                            case '-8.842'
                                zone1=-8.842;
                            case '-10.56'
                                zone1=-10.56;
                        end
                        switch app.Zone2materialKnob.Value
                            case {'0.36','0.3600'}                           
                                zone2=0.36;
                            case '-9.032'
                                zone2=-9.032;
                            case '-8.842'
                                zone2=-8.842;
                            case '-10.56'
                                zone2=-10.56;
                        end
                        switch app.Zone3materialKnob.Value
                            case {'0.36','0.3600'}
                                zone3=0.36;
                            case '-9.032'
                                zone3=-9.032;
                            case '-8.842'
                                zone3=-8.842;
                            case '-10.56'
                                zone3=-10.56;
                        end
                        switch app.Zone4materialKnob.Value
                            case {'0.36','0.3600'}
                                zone4=0.36;
                            case '-9.032'
                                zone4=-9.032;
                            case '-8.842'
                                zone4=-8.842;
                            case '-10.56'
                                zone4=-10.56;
                        end
                        shepplogan_sus_dist = SheppLogan(round(128/res), res, [zone1*10^-6 zone2*10^-6 zone3*10^-6 zone4*10^-6]);
                        shepplogan_dBz = FBFest(shepplogan_sus_dist.volume, shepplogan_sus_dist.image_res, shepplogan_sus_dist.matrix, 'SheppLogan' );
                        shepplogan_dBz.save('shepplogan_dBz.nii');
                        shepplogan_vol = NumericalModel('Shepp-Logan3d',round(128/res));
                        shepplogan_vol.generate_deltaB0('load_external', 'shepplogan_dBz.nii');
                        % Calculating for dual-echo
                        echoTimesD=zeros(1,2);
                        echoTimesD(1,1)=app.TE1sEditField.Value;
                        echoTimesD(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                        shepplogan_vol.simulate_measurement(90, echoTimesD, app.SNRSlider.Value);
                        trueMap=shepplogan_vol.deltaB0(:,:,round(64/res) )*(shepplogan_vol.gamma / (2*pi));
                        magn = shepplogan_vol.getMagnitude;
                        phase = shepplogan_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimesD);
                        generatedMap=dual_echo_delf(:,:,round(64/res));
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotDualEcho(find(xPlot==res))=Re;
                        % Calculating for multi-echo
                        echoTimesM=zeros(1,app.nTEEditField.Value);
                        for i=1:app.nTEEditField.Value
                            echoTimesM(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                        shepplogan_vol.simulate_measurement(90, echoTimesM, app.SNRSlider.Value);
                        trueMap=shepplogan_vol.deltaB0(:,:,round(64/res) )*(shepplogan_vol.gamma / (2*pi));
                        magn = shepplogan_vol.getMagnitude;
                        phase = shepplogan_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimesM); 
                        generatedMap=multi_echo_delf(:,:,round(64/res));
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotMultiEcho(find(xPlot==res))=Re;
                    case app.ZubalButton
                        zubal_sus_dist = Zubal('../zubal_EAO.nii');
                        % Resolution 
                        sz = size(zubal_sus_dist.volume);
                        xg = 1:sz(1);
                        yg = 1:sz(2);
                        F = griddedInterpolant({xg,yg},double(zubal_sus_dist.volume));
                        xq = linspace(1,sz(1),round(256/res))';
                        yq = linspace(1,sz(2),round(256/res))';
                        zubal_sus_dist.volume = F({xq,yq});
                        zubal_dBz = FBFest(zubal_sus_dist.volume, [res res res], [round(256/res) round(256/res) 128], 'Zubal' );
                        zubal_dBz.save('zubal_EAO_dBz.nii');
                        zubal_vol = NumericalModel('Zubal','ShimmingToolboxMatlab/zubal_EAO.nii');
                        zubal_vol.generate_deltaB0('load_external', 'zubal_EAO_dBz.nii');
                        % Resolution 
                        zubal_vol.numVox=[round(256/res) round(256/res) 128];
                        F = griddedInterpolant({xg,yg},double(zubal_vol.starting_volume));
                        zubal_vol.starting_volume=F({xq,yq});
                        F = griddedInterpolant({xg,yg},double(zubal_vol.volume.protonDensity));
                        zubal_vol.volume.protonDensity=F({xq,yq});
                        F = griddedInterpolant({xg,yg},double(zubal_vol.volume.T2star));
                        zubal_vol.volume.T2star=F({xq,yq});
                        % Calculating for dual-echo
                        echoTimesD=zeros(1,2);
                        echoTimesD(1,1)=app.TE1sEditField.Value;
                        echoTimesD(1,2)=app.TE1sEditField.Value+app.TEsEditField.Value;
                        zubal_vol.simulate_measurement(90, echoTimesD, app.SNRSlider.Value);
                        trueMap=zubal_vol.deltaB0(:,:,64)*(zubal_vol.gamma / (2*pi));
                        magn = zubal_vol.getMagnitude;
                        phase = zubal_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [dual_echo_delf] = +imutils.b0.dual_echo(compl_vol(:,:,:,1:2), echoTimesD);
                        generatedMap=dual_echo_delf(:,:,64);
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotDualEcho(find(xPlot==res))=Re;
                        % Calculating for multi-echo
                        for i=1:app.nTEEditField.Value
                            echoTimesM(1,i)=app.TE1sEditField_2.Value+((i-1)*app.TEsEditField_2.Value);
                        end
                        zubal_vol.simulate_measurement(90, echoTimesM, app.SNRSlider.Value);
                        trueMap=zubal_vol.deltaB0(:,:,64)*(zubal_vol.gamma / (2*pi));
                        magn = zubal_vol.getMagnitude;
                        phase = zubal_vol.getPhase;
                        compl_vol = magn.*exp(1i*phase);
                        [multi_echo_delf] = +imutils.b0.multiecho_linfit(compl_vol, echoTimesM); 
                        generatedMap=multi_echo_delf(:,:,64);
                        %SE = abs(trueMap-generatedMap).^2;
                        %MSE = sum(SE(:))/numel(trueMap);
                        Re=100*mean(abs((trueMap-generatedMap)./trueMap),'all');
                        yPlotMultiEcho(find(xPlot==res))=Re;
                end
            end
            plot(app.UIAxes2,xPlot,yPlotDualEcho)
            hold(app.UIAxes2,'on') 
            plot(app.UIAxes2,xPlot,yPlotMultiEcho)
            hold(app.UIAxes2,'off')
            xlabel(app.UIAxes2,'Resolution (mm x mm)')
            legend(app.UIAxes2,'Dual-Echo','Multi-Echo') 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Generated magnetic field map (Hz)')
            xlabel(app.UIAxes, 'Pixel position on x-axis')
            ylabel(app.UIAxes, 'Pixel position on y-axis')
            zlabel(app.UIAxes, 'Larmor frequency (Hz)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'True magnetic field map (Hz)')
            xlabel(app.UIAxes_2, 'Pixel position on x-axis ')
            ylabel(app.UIAxes_2, 'Pixel position on y-axis')
            zlabel(app.UIAxes_2, 'Larmor frequency (Hz)')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Difference between field maps (Hz)')
            xlabel(app.UIAxes_3, 'Pixel position on x-axis')
            ylabel(app.UIAxes_3, 'Pixel position on y-axis')
            zlabel(app.UIAxes_3, 'Larmor frequency (Hz)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Plotted relative error')
            xlabel(app.UIAxes2, 'SNR or resolution')
            ylabel(app.UIAxes2, 'Relative error (%)')
            zlabel(app.UIAxes2, 'Z')
            app.FieldmapmodelButtonGroup = uibuttongroup(app.UIFigure);
            app.FieldmapmodelButtonGroup.Title = 'Field map model';
            app.SphericalButton = uitogglebutton(app.FieldmapmodelButtonGroup);
            app.SphericalButton.Text = 'Spherical';
            app.SphericalButton.Value = true;
            app.SheppLoganButton = uitogglebutton(app.FieldmapmodelButtonGroup);
            app.SheppLoganButton.Tag = 'shepplogan';
            app.SheppLoganButton.Text = 'Shepp-Logan';
            app.ZubalButton = uitogglebutton(app.FieldmapmodelButtonGroup);
            app.ZubalButton.Text = 'Zubal';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Method';
            app.MethodButtonGroup.Tag = 'method';
            app.DualEchoButton = uitogglebutton(app.MethodButtonGroup);
            app.DualEchoButton.Text = 'Dual-Echo';
            app.DualEchoButton.Value = true;
            app.MultiEchoButton = uitogglebutton(app.MethodButtonGroup);
            app.MultiEchoButton.Text = 'Multi-Echo';
            app.SimulategraphanddataButton = uibutton(app.UIFigure, 'push');
            app.SimulategraphanddataButton.ButtonPushedFcn = createCallbackFcn(app, @SimulategraphanddataButtonPushed, true);
            app.SimulategraphanddataButton.Text = 'Simulate graph and data';
            app.SNRSliderLabel = uilabel(app.UIFigure);
            app.SNRSliderLabel.Text = 'SNR';
            app.SNRSlider = uislider(app.UIFigure);
            app.SNRSlider.Limits = [50 500];
            app.SNRSlider.Value = 100;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Model'; 'Method'; 'SNR'; 'Resolution'; 'Relative error (%)'; 'Mean squared error'};
            app.UITable.RowName = {};
            app.DeleterowButton = uibutton(app.UIFigure, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row ';
            app.RownumberLabel = uilabel(app.UIFigure);
            app.RownumberLabel.Text = 'Row number:';
            app.RownumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.RownumberEditField.RoundFractionalValues = 'on';
            app.RownumberEditField.Value = 1;
            app.CalculateaverageerrorButton = uibutton(app.UIFigure, 'push');
            app.CalculateaverageerrorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateaverageerrorButtonPushed, true);
            app.CalculateaverageerrorButton.Text = 'Calculate average error';
            app.AveragerelativeerrorEditFieldLabel = uilabel(app.UIFigure);
            app.AveragerelativeerrorEditFieldLabel.Text = 'Average relative error =';
            app.AveragerelativeerrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.SphericalmodelpropertiesLabel = uilabel(app.UIFigure);
            app.SphericalmodelpropertiesLabel.Text = 'Spherical model properties';
            app.SheppLoganmodelpropertiesLabel = uilabel(app.UIFigure);
            app.SheppLoganmodelpropertiesLabel.Text = 'Shepp-Logan model properties';
            app.InsidematerialKnobLabel = uilabel(app.UIFigure);
            app.InsidematerialKnobLabel.Text = 'Inside material';
            app.InsidematerialKnob = uiknob(app.UIFigure, 'discrete');
            app.InsidematerialKnob.Items = {'Air', 'Water', 'Pure mineral oil', 'Silicone oil'};
            app.InsidematerialKnob.ItemsData = {'0.36', '-9.032', '-8.842', '-10.56'};
            app.InsidematerialKnob.Value = '0.36';
            app.OutsidematerialKnobLabel = uilabel(app.UIFigure);
            app.OutsidematerialKnobLabel.Text = 'Outside material';
            app.OutsidematerialKnob = uiknob(app.UIFigure, 'discrete');
            app.OutsidematerialKnob.Items = {'Air', 'Water', 'Pure mineral oil', 'Silicone oil'};
            app.OutsidematerialKnob.ItemsData = {'0.36', '-9.032', '-8.842', '-10.56'};
            app.OutsidematerialKnob.Value = '-9.032';
            app.Zone1materialKnobLabel = uilabel(app.UIFigure);
            app.Zone1materialKnobLabel.Text = 'Zone 1 material';
            app.Zone1materialKnob = uiknob(app.UIFigure, 'discrete');
            app.Zone1materialKnob.Items = {'Air', 'Water', 'Pure mineral oil', 'Silicone oil'};
            app.Zone1materialKnob.ItemsData = {'0.36', '-9.032', '-8.842', '-10.56'};
            app.Zone1materialKnob.Value = '0.36';
            app.Zone4materialKnobLabel = uilabel(app.UIFigure);
            app.Zone4materialKnobLabel.Text = 'Zone 4 material';
            app.Zone4materialKnob = uiknob(app.UIFigure, 'discrete');
            app.Zone4materialKnob.Items = {'Air', 'Water', 'Pure mineral oil', 'Silicone oil'};
            app.Zone4materialKnob.ItemsData = {'0.36', '-9.032', '-8.842', '-10.56'};
            app.Zone4materialKnob.Value = '-10.56';
            app.Zone2materialKnobLabel = uilabel(app.UIFigure);
            app.Zone2materialKnobLabel.Text = 'Zone 2 material';
            app.Zone2materialKnob = uiknob(app.UIFigure, 'discrete');
            app.Zone2materialKnob.Items = {'Air', 'Water', 'Pure mineral oil', 'Silicone oil'};
            app.Zone2materialKnob.ItemsData = {'0.36', '-9.032', '-8.842', '-10.56'};
            app.Zone2materialKnob.Value = '-9.032';
            app.Zone3materialKnobLabel = uilabel(app.UIFigure);
            app.Zone3materialKnobLabel.Text = 'Zone 3 material';
            app.Zone3materialKnob = uiknob(app.UIFigure, 'discrete');
            app.Zone3materialKnob.Items = {'Air', 'Water', 'Pure mineral oil', 'Silicone oil'};
            app.Zone3materialKnob.ItemsData = {'0.36', '-9.032', '-8.842', '-10.56'};
            app.Zone3materialKnob.Value = '-8.842';
            app.ResolutionmmxmmSpinnerLabel = uilabel(app.UIFigure);
            app.ResolutionmmxmmSpinnerLabel.Text = 'Resolution (mm x mm)';
            app.ResolutionmmxmmSpinner = uispinner(app.UIFigure);
            app.ResolutionmmxmmSpinner.Limits = [1 10];
            app.ResolutionmmxmmSpinner.Value = 1;
            app.TEsEditField_2Label = uilabel(app.UIFigure);
            app.TEsEditField_2Label.Text = '𐤃TE (s)';
            app.TEsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TEsEditField_2.Value = 0.001;
            app.TE1sEditField_2Label = uilabel(app.UIFigure);
            app.TE1sEditField_2Label.Text = 'TE1 (s)';
            app.TE1sEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TE1sEditField_2.Value = 0.001;
            app.nTEEditFieldLabel = uilabel(app.UIFigure);
            app.nTEEditFieldLabel.Text = 'nTE';
            app.nTEEditField = uieditfield(app.UIFigure, 'numeric');
            app.nTEEditField.Value = 6;
            app.TE1sEditFieldLabel = uilabel(app.UIFigure);
            app.TE1sEditFieldLabel.Text = 'TE1 (s)';
            app.TE1sEditField = uieditfield(app.UIFigure, 'numeric');
            app.TE1sEditField.Value = 0.001;
            app.TEsEditFieldLabel = uilabel(app.UIFigure);
            app.TEsEditFieldLabel.Text = '𐤃TE (s)';
            app.TEsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEsEditField.Value = 0.001;
            app.MultiEchotimesLabel = uilabel(app.UIFigure);
            app.MultiEchotimesLabel.Text = 'Multi-Echo times ';
            app.DualEchotimesLabel = uilabel(app.UIFigure);
            app.DualEchotimesLabel.Text = 'Dual-Echo times ';
            app.PlotSNRButton = uibutton(app.UIFigure, 'push');
            app.PlotSNRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSNRButtonPushed, true);
            app.PlotSNRButton.Text = 'Plot SNR';
            app.PlotResolutionButton = uibutton(app.UIFigure, 'push');
            app.PlotResolutionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResolutionButtonPushed, true);
            app.PlotResolutionButton.Text = 'Plot Resolution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceBOMapping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end